classdef Try < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                      matlab.ui.container.GridLayout
        SimulationPanel                 matlab.ui.container.Panel
        QLabel                          matlab.ui.control.Label
        BERLabel                        matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        ModulationTypeDropDown          matlab.ui.control.DropDown
        ModulationTypeDropDownLabel     matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        DSPPanel                        matlab.ui.container.Panel
        PhaserecoveryDropDown           matlab.ui.control.DropDown
        PhaserecoveryDropDownLabel      matlab.ui.control.Label
        EqualizerDropDown               matlab.ui.control.DropDown
        EqualizerDropDownLabel          matlab.ui.control.Label
        ResampingCheckBox               matlab.ui.control.CheckBox
        DispersioncompensationCheckBox  matlab.ui.control.CheckBox
        LocaloscillatorPanel            matlab.ui.container.Panel
        DampingfactornsEditField        matlab.ui.control.EditField
        DampingfactornsEditFieldLabel   matlab.ui.control.Label
        fnoiseEditField                 matlab.ui.control.EditField
        fnoiseEditFieldLabel            matlab.ui.control.Label
        resonancefrequencyEditField     matlab.ui.control.EditField
        resonancefrequencyLabel         matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.EditField
        alphaLabel                      matlab.ui.control.Label
        LinewidthkHzEditField           matlab.ui.control.EditField
        LinewidthkHzEditFieldLabel      matlab.ui.control.Label
        CarrierfrequencyTHzEditField_2  matlab.ui.control.EditField
        CarrierfrequencyTHzEditField_2Label  matlab.ui.control.Label
        LODropDown                      matlab.ui.control.DropDown
        LODropDownLabel                 matlab.ui.control.Label
        FiberChannelPanel               matlab.ui.container.Panel
        DispersionpsnmkmEditField       matlab.ui.control.EditField
        DispersionpsnmkmEditFieldLabel  matlab.ui.control.Label
        EDFACheckBox                    matlab.ui.control.CheckBox
        OSNRpdBEditField                matlab.ui.control.EditField
        OSNRpdBEditFieldLabel           matlab.ui.control.Label
        AttenuationdBkmEditField        matlab.ui.control.EditField
        AttenuationdBkmEditFieldLabel   matlab.ui.control.Label
        LengthkmEditField               matlab.ui.control.EditField
        LengthkmLabel                   matlab.ui.control.Label
        TransmitterPanel                matlab.ui.container.Panel
        PulseShapeDropDown              matlab.ui.control.DropDown
        PulseShapeLabel                 matlab.ui.control.Label
        rollofffactorEditFieldLabel     matlab.ui.control.Label
        rollofffactorEditField          matlab.ui.control.EditField
        linewidthkHzEditFieldLabel      matlab.ui.control.Label
        linewidthkHzEditField           matlab.ui.control.EditField
        samplesPerSymbolEditFieldLabel  matlab.ui.control.Label
        samplesPerSymbolEditField       matlab.ui.control.EditField
        CarrierfrequencyTHzEditFieldLabel  matlab.ui.control.Label
        CarrierfrequencyTHzEditField    matlab.ui.control.EditField
        SymbolRateLabel                 matlab.ui.control.Label
        SymbolRateGbaudEditField        matlab.ui.control.EditField
        NumberofmodesEditFieldLabel     matlab.ui.control.Label
        NumberofmodesEditField          matlab.ui.control.EditField
        ModulationOrderEditField        matlab.ui.control.EditField
        ModulationOrderEditFieldLabel   matlab.ui.control.Label
        DeviceLibraryLabel              matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        ResultsPanel                    matlab.ui.container.Panel
        ResultSignal                    matlab.ui.control.UIAxes
        BlockDiagramPanel               matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        ADCPanel                        matlab.ui.container.Panel
        IFGHzEditField_2                matlab.ui.control.EditField
        IFGHzEditField_2Label           matlab.ui.control.Label
        MixerPanel                      matlab.ui.container.Panel
        IFGHzEditField                  matlab.ui.control.EditField
        IFGHzEditFieldLabel             matlab.ui.control.Label
        WirelesschannelPanel            matlab.ui.container.Panel
        ChannelmodelDropDown            matlab.ui.control.DropDown
        ChannelmodelDropDownLabel       matlab.ui.control.Label
        SNREditField                    matlab.ui.control.EditField
        SNRLabel                        matlab.ui.control.Label
        PDPanel                         matlab.ui.container.Panel
        ShotnoiseCheckBox               matlab.ui.control.CheckBox
        ThermalnoiseCheckBox            matlab.ui.control.CheckBox
        BandpassfilterDropDown          matlab.ui.control.DropDown
        BandpassfilterDropDownLabel     matlab.ui.control.Label
        FilterOrderpsnmkmEditField      matlab.ui.control.EditField
        FilterOrderpsnmkmEditFieldLabel  matlab.ui.control.Label
        FilterBandeidthpsnmkmEditField  matlab.ui.control.EditField
        FilterBandeidthpsnmkmEditFieldLabel  matlab.ui.control.Label
        DarkcurrentAEditField           matlab.ui.control.EditField
        DarkcurrentAEditFieldLabel      matlab.ui.control.Label
        ResponsivityAWEditField         matlab.ui.control.EditField
        ResponsivityAWEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModulationOrderEditField
        function ModulationOrderEditFieldValueChanged(comp, event)
            value = comp.ModulationOrderEditField.Value;
            
        end

        % Value changed function: samplesPerSymbolEditField
        function samplesPerSymbolEditFieldValueChanged(comp, event)
            value = comp.samplesPerSymbolEditField.Value;
  

        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(comp, event)
         [BER,Q]=Sim_Fun();
         comp.BERLabel.Text="BER: "+num2str(BER);
         comp.QLabel.Text="Q: "+num2str(Q);
          % 指定图像路径
    imagePath = 'D:\Matlab Designer\Pic\result.png'; % 替换为你的图像路径

    % 读取图像
    img = imread(imagePath);

    % 显示图像在 UIAxes 中
    imshow(img, 'Parent', comp.ResultSignal);
         close all
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
            
        end

        % 创建底层组件
        function setup(comp)

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            comp.Position = [1 1 1040 975];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'1x'};
            comp.GridLayout.RowHeight = {'0.1x', '1x'};

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout2.ColumnWidth = {'1.2x', '2.4x'};
            comp.GridLayout2.RowHeight = {'1x'};
            comp.GridLayout2.Layout.Row = 2;
            comp.GridLayout2.Layout.Column = 1;

            % Create GridLayout4
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.GridLayout4.ColumnWidth = {'1x'};
            comp.GridLayout4.RowHeight = {230, '0.4x', '1.4x'};
            comp.GridLayout4.Layout.Row = 1;
            comp.GridLayout4.Layout.Column = 2;

            % Create GridLayout5
            comp.GridLayout5 = uigridlayout(comp.GridLayout4);
            comp.GridLayout5.ColumnWidth = {'0.5x', '0.5x'};
            comp.GridLayout5.RowHeight = {'1x'};
            comp.GridLayout5.Padding = [0 0 0 0];
            comp.GridLayout5.Layout.Row = 1;
            comp.GridLayout5.Layout.Column = 1;

            % Create PDPanel
            comp.PDPanel = uipanel(comp.GridLayout5);
            comp.PDPanel.Title = 'PD';
            comp.PDPanel.Layout.Row = 1;
            comp.PDPanel.Layout.Column = 1;

            % Create ResponsivityAWEditFieldLabel
            comp.ResponsivityAWEditFieldLabel = uilabel(comp.PDPanel);
            comp.ResponsivityAWEditFieldLabel.Position = [14 178 106 22];
            comp.ResponsivityAWEditFieldLabel.Text = 'Responsivity(A/W):';

            % Create ResponsivityAWEditField
            comp.ResponsivityAWEditField = uieditfield(comp.PDPanel, 'text');
            comp.ResponsivityAWEditField.Position = [222 178 76 22];

            % Create DarkcurrentAEditFieldLabel
            comp.DarkcurrentAEditFieldLabel = uilabel(comp.PDPanel);
            comp.DarkcurrentAEditFieldLabel.Position = [13 151 91 22];
            comp.DarkcurrentAEditFieldLabel.Text = 'Dark current(A):';

            % Create DarkcurrentAEditField
            comp.DarkcurrentAEditField = uieditfield(comp.PDPanel, 'text');
            comp.DarkcurrentAEditField.Position = [222 151 76 22];

            % Create FilterBandeidthpsnmkmEditFieldLabel
            comp.FilterBandeidthpsnmkmEditFieldLabel = uilabel(comp.PDPanel);
            comp.FilterBandeidthpsnmkmEditFieldLabel.Position = [13 94 161 22];
            comp.FilterBandeidthpsnmkmEditFieldLabel.Text = 'Filter Bandeidth:(ps/(nm.km))';

            % Create FilterBandeidthpsnmkmEditField
            comp.FilterBandeidthpsnmkmEditField = uieditfield(comp.PDPanel, 'text');
            comp.FilterBandeidthpsnmkmEditField.Position = [222 94 77 22];

            % Create FilterOrderpsnmkmEditFieldLabel
            comp.FilterOrderpsnmkmEditFieldLabel = uilabel(comp.PDPanel);
            comp.FilterOrderpsnmkmEditFieldLabel.Position = [14 69 136 22];
            comp.FilterOrderpsnmkmEditFieldLabel.Text = 'Filter Order:(ps/(nm.km))';

            % Create FilterOrderpsnmkmEditField
            comp.FilterOrderpsnmkmEditField = uieditfield(comp.PDPanel, 'text');
            comp.FilterOrderpsnmkmEditField.Position = [222 69 78 22];

            % Create BandpassfilterDropDownLabel
            comp.BandpassfilterDropDownLabel = uilabel(comp.PDPanel);
            comp.BandpassfilterDropDownLabel.Position = [15 121 87 22];
            comp.BandpassfilterDropDownLabel.Text = 'Bandpass filter:';

            % Create BandpassfilterDropDown
            comp.BandpassfilterDropDown = uidropdown(comp.PDPanel);
            comp.BandpassfilterDropDown.Position = [212 121 88 22];

            % Create ThermalnoiseCheckBox
            comp.ThermalnoiseCheckBox = uicheckbox(comp.PDPanel);
            comp.ThermalnoiseCheckBox.Text = 'Thermal noise';
            comp.ThermalnoiseCheckBox.Position = [13 42 105 22];

            % Create ShotnoiseCheckBox
            comp.ShotnoiseCheckBox = uicheckbox(comp.PDPanel);
            comp.ShotnoiseCheckBox.Text = 'Shot noise';
            comp.ShotnoiseCheckBox.Position = [13 18 86 22];

            % Create GridLayout6
            comp.GridLayout6 = uigridlayout(comp.GridLayout5);
            comp.GridLayout6.ColumnWidth = {'1x'};
            comp.GridLayout6.RowHeight = {80, '1x', '1x'};
            comp.GridLayout6.Padding = [0 0 0 0];
            comp.GridLayout6.Layout.Row = 1;
            comp.GridLayout6.Layout.Column = 2;

            % Create WirelesschannelPanel
            comp.WirelesschannelPanel = uipanel(comp.GridLayout6);
            comp.WirelesschannelPanel.Title = 'Wireless channel';
            comp.WirelesschannelPanel.Layout.Row = 1;
            comp.WirelesschannelPanel.Layout.Column = 1;

            % Create SNRLabel
            comp.SNRLabel = uilabel(comp.WirelesschannelPanel);
            comp.SNRLabel.Position = [10 7 34 22];
            comp.SNRLabel.Text = 'SNR:';

            % Create SNREditField
            comp.SNREditField = uieditfield(comp.WirelesschannelPanel, 'text');
            comp.SNREditField.Position = [225 7 71 22];

            % Create ChannelmodelDropDownLabel
            comp.ChannelmodelDropDownLabel = uilabel(comp.WirelesschannelPanel);
            comp.ChannelmodelDropDownLabel.Position = [10 32 86 22];
            comp.ChannelmodelDropDownLabel.Text = 'Channel model:';

            % Create ChannelmodelDropDown
            comp.ChannelmodelDropDown = uidropdown(comp.WirelesschannelPanel);
            comp.ChannelmodelDropDown.Position = [208 32 87 22];

            % Create MixerPanel
            comp.MixerPanel = uipanel(comp.GridLayout6);
            comp.MixerPanel.Title = 'Mixer';
            comp.MixerPanel.Layout.Row = 2;
            comp.MixerPanel.Layout.Column = 1;

            % Create IFGHzEditFieldLabel
            comp.IFGHzEditFieldLabel = uilabel(comp.MixerPanel);
            comp.IFGHzEditFieldLabel.Position = [10 12 60 22];
            comp.IFGHzEditFieldLabel.Text = 'IF(GHz):';

            % Create IFGHzEditField
            comp.IFGHzEditField = uieditfield(comp.MixerPanel, 'text');
            comp.IFGHzEditField.Position = [225 12 70 22];

            % Create ADCPanel
            comp.ADCPanel = uipanel(comp.GridLayout6);
            comp.ADCPanel.Title = 'ADC';
            comp.ADCPanel.Layout.Row = 3;
            comp.ADCPanel.Layout.Column = 1;

            % Create IFGHzEditField_2Label
            comp.IFGHzEditField_2Label = uilabel(comp.ADCPanel);
            comp.IFGHzEditField_2Label.Position = [10 13 53 22];
            comp.IFGHzEditField_2Label.Text = 'IF(GHz):';

            % Create IFGHzEditField_2
            comp.IFGHzEditField_2 = uieditfield(comp.ADCPanel, 'text');
            comp.IFGHzEditField_2.Position = [225 13 71 22];

            % Create BlockDiagramPanel
            comp.BlockDiagramPanel = uipanel(comp.GridLayout4);
            comp.BlockDiagramPanel.Title = 'Block Diagram';
            comp.BlockDiagramPanel.BackgroundColor = [1 1 1];
            comp.BlockDiagramPanel.Layout.Row = 2;
            comp.BlockDiagramPanel.Layout.Column = 1;

            % Create Image
            comp.Image = uiimage(comp.BlockDiagramPanel);
            comp.Image.Position = [135 -68 373 248];
            comp.Image.ImageSource = fullfile(pathToMLAPP, '通信系统框图.jpg');

            % Create ResultsPanel
            comp.ResultsPanel = uipanel(comp.GridLayout4);
            comp.ResultsPanel.Title = 'Results';
            comp.ResultsPanel.Layout.Row = 3;
            comp.ResultsPanel.Layout.Column = 1;

            % Create ResultSignal
            comp.ResultSignal = uiaxes(comp.ResultsPanel);
            comp.ResultSignal.Position = [1 0 637 421];

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.GridLayout3.ColumnWidth = {'1x'};
            comp.GridLayout3.RowHeight = {20, 250, 160, 230, 130};
            comp.GridLayout3.Layout.Row = 1;
            comp.GridLayout3.Layout.Column = 1;

            % Create DeviceLibraryLabel
            comp.DeviceLibraryLabel = uilabel(comp.GridLayout3);
            comp.DeviceLibraryLabel.BackgroundColor = [1 1 1];
            comp.DeviceLibraryLabel.HorizontalAlignment = 'center';
            comp.DeviceLibraryLabel.FontWeight = 'bold';
            comp.DeviceLibraryLabel.Layout.Row = 1;
            comp.DeviceLibraryLabel.Layout.Column = 1;
            comp.DeviceLibraryLabel.Text = 'Device Library';

            % Create TransmitterPanel
            comp.TransmitterPanel = uipanel(comp.GridLayout3);
            comp.TransmitterPanel.Title = 'Transmitter';
            comp.TransmitterPanel.Layout.Row = 2;
            comp.TransmitterPanel.Layout.Column = 1;

            % Create ModulationOrderEditFieldLabel
            comp.ModulationOrderEditFieldLabel = uilabel(comp.TransmitterPanel);
            comp.ModulationOrderEditFieldLabel.FontSize = 8;
            comp.ModulationOrderEditFieldLabel.Position = [11 200 101 22];
            comp.ModulationOrderEditFieldLabel.Text = 'Modulation Order:';

            % Create ModulationOrderEditField
            comp.ModulationOrderEditField = uieditfield(comp.TransmitterPanel, 'text');
            comp.ModulationOrderEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ModulationOrderEditFieldValueChanged, true);
            comp.ModulationOrderEditField.Position = [219 202 77 20];

            % Create NumberofmodesEditField
            comp.NumberofmodesEditField = uieditfield(comp.TransmitterPanel, 'text');
            comp.NumberofmodesEditField.Position = [219 173 77 21];

            % Create NumberofmodesEditFieldLabel
            comp.NumberofmodesEditFieldLabel = uilabel(comp.TransmitterPanel);
            comp.NumberofmodesEditFieldLabel.FontSize = 8;
            comp.NumberofmodesEditFieldLabel.Position = [12 172 103 22];
            comp.NumberofmodesEditFieldLabel.Text = 'Number of modes:';

            % Create SymbolRateGbaudEditField
            comp.SymbolRateGbaudEditField = uieditfield(comp.TransmitterPanel, 'text');
            comp.SymbolRateGbaudEditField.HorizontalAlignment = 'center';
            comp.SymbolRateGbaudEditField.Position = [219 144 77 22];

            % Create SymbolRateLabel
            comp.SymbolRateLabel = uilabel(comp.TransmitterPanel);
            comp.SymbolRateLabel.FontSize = 8;
            comp.SymbolRateLabel.Position = [12 144 118 22];
            comp.SymbolRateLabel.Text = 'SymbolRate(Gbaud):';

            % Create CarrierfrequencyTHzEditField
            comp.CarrierfrequencyTHzEditField = uieditfield(comp.TransmitterPanel, 'text');
            comp.CarrierfrequencyTHzEditField.Position = [219 116 77 22];

            % Create CarrierfrequencyTHzEditFieldLabel
            comp.CarrierfrequencyTHzEditFieldLabel = uilabel(comp.TransmitterPanel);
            comp.CarrierfrequencyTHzEditFieldLabel.FontSize = 8;
            comp.CarrierfrequencyTHzEditFieldLabel.Position = [12 116 131 22];
            comp.CarrierfrequencyTHzEditFieldLabel.Text = 'Carrier frequency(THz):';

            % Create samplesPerSymbolEditField
            comp.samplesPerSymbolEditField = uieditfield(comp.TransmitterPanel, 'text');
            comp.samplesPerSymbolEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @samplesPerSymbolEditFieldValueChanged, true);
            comp.samplesPerSymbolEditField.Position = [219 88 77 22];

            % Create samplesPerSymbolEditFieldLabel
            comp.samplesPerSymbolEditFieldLabel = uilabel(comp.TransmitterPanel);
            comp.samplesPerSymbolEditFieldLabel.FontSize = 8;
            comp.samplesPerSymbolEditFieldLabel.Position = [12 88 111 22];
            comp.samplesPerSymbolEditFieldLabel.Text = 'samplesPerSymbol:';

            % Create linewidthkHzEditField
            comp.linewidthkHzEditField = uieditfield(comp.TransmitterPanel, 'text');
            comp.linewidthkHzEditField.Position = [219 60 77 22];

            % Create linewidthkHzEditFieldLabel
            comp.linewidthkHzEditFieldLabel = uilabel(comp.TransmitterPanel);
            comp.linewidthkHzEditFieldLabel.FontSize = 8;
            comp.linewidthkHzEditFieldLabel.Position = [12 60 83 22];
            comp.linewidthkHzEditFieldLabel.Text = 'linewidth(kHz):';

            % Create rollofffactorEditField
            comp.rollofffactorEditField = uieditfield(comp.TransmitterPanel, 'text');
            comp.rollofffactorEditField.Position = [219 5 77 22];

            % Create rollofffactorEditFieldLabel
            comp.rollofffactorEditFieldLabel = uilabel(comp.TransmitterPanel);
            comp.rollofffactorEditFieldLabel.FontSize = 8;
            comp.rollofffactorEditFieldLabel.Position = [12 5 75 22];
            comp.rollofffactorEditFieldLabel.Text = 'roll-off factor:';

            % Create PulseShapeLabel
            comp.PulseShapeLabel = uilabel(comp.TransmitterPanel);
            comp.PulseShapeLabel.FontSize = 8;
            comp.PulseShapeLabel.Position = [12 32 75 22];
            comp.PulseShapeLabel.Text = 'Pulse Shape:';

            % Create PulseShapeDropDown
            comp.PulseShapeDropDown = uidropdown(comp.TransmitterPanel);
            comp.PulseShapeDropDown.FontSize = 8;
            comp.PulseShapeDropDown.Position = [210 32 86 22];

            % Create FiberChannelPanel
            comp.FiberChannelPanel = uipanel(comp.GridLayout3);
            comp.FiberChannelPanel.Title = 'Fiber Channel';
            comp.FiberChannelPanel.Layout.Row = 3;
            comp.FiberChannelPanel.Layout.Column = 1;

            % Create LengthkmLabel
            comp.LengthkmLabel = uilabel(comp.FiberChannelPanel);
            comp.LengthkmLabel.FontSize = 8;
            comp.LengthkmLabel.Position = [13 109 72 22];
            comp.LengthkmLabel.Text = 'Length (km):';

            % Create LengthkmEditField
            comp.LengthkmEditField = uieditfield(comp.FiberChannelPanel, 'text');
            comp.LengthkmEditField.Position = [220 109 77 22];

            % Create AttenuationdBkmEditFieldLabel
            comp.AttenuationdBkmEditFieldLabel = uilabel(comp.FiberChannelPanel);
            comp.AttenuationdBkmEditFieldLabel.FontSize = 8;
            comp.AttenuationdBkmEditFieldLabel.Position = [13 84 111 22];
            comp.AttenuationdBkmEditFieldLabel.Text = 'Attenuation(dB/km):';

            % Create AttenuationdBkmEditField
            comp.AttenuationdBkmEditField = uieditfield(comp.FiberChannelPanel, 'text');
            comp.AttenuationdBkmEditField.Position = [220 84 76 22];

            % Create OSNRpdBEditFieldLabel
            comp.OSNRpdBEditFieldLabel = uilabel(comp.FiberChannelPanel);
            comp.OSNRpdBEditFieldLabel.FontSize = 8;
            comp.OSNRpdBEditFieldLabel.Position = [13 22 72 22];
            comp.OSNRpdBEditFieldLabel.Text = 'OSNR:(pdB)';

            % Create OSNRpdBEditField
            comp.OSNRpdBEditField = uieditfield(comp.FiberChannelPanel, 'text');
            comp.OSNRpdBEditField.Position = [220 22 77 22];

            % Create EDFACheckBox
            comp.EDFACheckBox = uicheckbox(comp.FiberChannelPanel);
            comp.EDFACheckBox.Text = 'EDFA';
            comp.EDFACheckBox.Position = [12 0 53 22];

            % Create DispersionpsnmkmEditFieldLabel
            comp.DispersionpsnmkmEditFieldLabel = uilabel(comp.FiberChannelPanel);
            comp.DispersionpsnmkmEditFieldLabel.Position = [13 51 158 22];
            comp.DispersionpsnmkmEditFieldLabel.Text = 'Dispersion:(ps/(nm.km))';

            % Create DispersionpsnmkmEditField
            comp.DispersionpsnmkmEditField = uieditfield(comp.FiberChannelPanel, 'text');
            comp.DispersionpsnmkmEditField.Position = [219 51 78 22];

            % Create LocaloscillatorPanel
            comp.LocaloscillatorPanel = uipanel(comp.GridLayout3);
            comp.LocaloscillatorPanel.Title = 'Local oscillator';
            comp.LocaloscillatorPanel.Layout.Row = 4;
            comp.LocaloscillatorPanel.Layout.Column = 1;

            % Create LODropDownLabel
            comp.LODropDownLabel = uilabel(comp.LocaloscillatorPanel);
            comp.LODropDownLabel.FontSize = 8;
            comp.LODropDownLabel.Position = [13 176 56 22];
            comp.LODropDownLabel.Text = 'LO:';

            % Create LODropDown
            comp.LODropDown = uidropdown(comp.LocaloscillatorPanel);
            comp.LODropDown.Position = [188 176 100 22];

            % Create CarrierfrequencyTHzEditField_2Label
            comp.CarrierfrequencyTHzEditField_2Label = uilabel(comp.LocaloscillatorPanel);
            comp.CarrierfrequencyTHzEditField_2Label.Position = [13 148 131 22];
            comp.CarrierfrequencyTHzEditField_2Label.Text = 'Carrier frequency(THz):';

            % Create CarrierfrequencyTHzEditField_2
            comp.CarrierfrequencyTHzEditField_2 = uieditfield(comp.LocaloscillatorPanel, 'text');
            comp.CarrierfrequencyTHzEditField_2.Position = [220 148 77 22];

            % Create LinewidthkHzEditFieldLabel
            comp.LinewidthkHzEditFieldLabel = uilabel(comp.LocaloscillatorPanel);
            comp.LinewidthkHzEditFieldLabel.Position = [13 119 87 22];
            comp.LinewidthkHzEditFieldLabel.Text = 'Linewidth(kHz):';

            % Create LinewidthkHzEditField
            comp.LinewidthkHzEditField = uieditfield(comp.LocaloscillatorPanel, 'text');
            comp.LinewidthkHzEditField.Position = [220 119 76 22];

            % Create alphaLabel
            comp.alphaLabel = uilabel(comp.LocaloscillatorPanel);
            comp.alphaLabel.FontColor = [0.8 0.8 0.8];
            comp.alphaLabel.Position = [13 92 53 22];
            comp.alphaLabel.Text = 'alpha:';

            % Create alphaEditField
            comp.alphaEditField = uieditfield(comp.LocaloscillatorPanel, 'text');
            comp.alphaEditField.Position = [220 92 76 22];

            % Create resonancefrequencyLabel
            comp.resonancefrequencyLabel = uilabel(comp.LocaloscillatorPanel);
            comp.resonancefrequencyLabel.FontColor = [0.8 0.8 0.8];
            comp.resonancefrequencyLabel.Position = [13 64 120 22];
            comp.resonancefrequencyLabel.Text = 'resonance frequency:';

            % Create resonancefrequencyEditField
            comp.resonancefrequencyEditField = uieditfield(comp.LocaloscillatorPanel, 'text');
            comp.resonancefrequencyEditField.Position = [219 64 77 22];

            % Create fnoiseEditFieldLabel
            comp.fnoiseEditFieldLabel = uilabel(comp.LocaloscillatorPanel);
            comp.fnoiseEditFieldLabel.FontColor = [0.8 0.8 0.8];
            comp.fnoiseEditFieldLabel.Position = [13 37 54 22];
            comp.fnoiseEditFieldLabel.Text = '1/f noise:';

            % Create fnoiseEditField
            comp.fnoiseEditField = uieditfield(comp.LocaloscillatorPanel, 'text');
            comp.fnoiseEditField.Position = [221 37 75 22];

            % Create DampingfactornsEditFieldLabel
            comp.DampingfactornsEditFieldLabel = uilabel(comp.LocaloscillatorPanel);
            comp.DampingfactornsEditFieldLabel.FontColor = [0.8 0.8 0.8];
            comp.DampingfactornsEditFieldLabel.Position = [13 10 114 22];
            comp.DampingfactornsEditFieldLabel.Text = 'Damping factor (ns):';

            % Create DampingfactornsEditField
            comp.DampingfactornsEditField = uieditfield(comp.LocaloscillatorPanel, 'text');
            comp.DampingfactornsEditField.Position = [221 10 75 22];

            % Create DSPPanel
            comp.DSPPanel = uipanel(comp.GridLayout3);
            comp.DSPPanel.Title = 'DSP';
            comp.DSPPanel.Layout.Row = 5;
            comp.DSPPanel.Layout.Column = 1;

            % Create DispersioncompensationCheckBox
            comp.DispersioncompensationCheckBox = uicheckbox(comp.DSPPanel);
            comp.DispersioncompensationCheckBox.Text = 'Dispersion compensation:';
            comp.DispersioncompensationCheckBox.Position = [13 83 170 22];

            % Create ResampingCheckBox
            comp.ResampingCheckBox = uicheckbox(comp.DSPPanel);
            comp.ResampingCheckBox.Text = 'Resamping:';
            comp.ResampingCheckBox.Position = [13 62 89 22];

            % Create EqualizerDropDownLabel
            comp.EqualizerDropDownLabel = uilabel(comp.DSPPanel);
            comp.EqualizerDropDownLabel.Position = [13 41 65 22];
            comp.EqualizerDropDownLabel.Text = 'Equalizer:';

            % Create EqualizerDropDown
            comp.EqualizerDropDown = uidropdown(comp.DSPPanel);
            comp.EqualizerDropDown.Position = [219 41 78 22];

            % Create PhaserecoveryDropDownLabel
            comp.PhaserecoveryDropDownLabel = uilabel(comp.DSPPanel);
            comp.PhaserecoveryDropDownLabel.HorizontalAlignment = 'right';
            comp.PhaserecoveryDropDownLabel.Position = [13 10 89 22];
            comp.PhaserecoveryDropDownLabel.Text = 'Phase recovery:';

            % Create PhaserecoveryDropDown
            comp.PhaserecoveryDropDown = uidropdown(comp.DSPPanel);
            comp.PhaserecoveryDropDown.Position = [219 10 78 22];

            % Create SimulationPanel
            comp.SimulationPanel = uipanel(comp.GridLayout);
            comp.SimulationPanel.BorderType = 'none';
            comp.SimulationPanel.Title = 'Simulation';
            comp.SimulationPanel.Layout.Row = 1;
            comp.SimulationPanel.Layout.Column = 1;

            % Create ModulationTypeDropDownLabel
            comp.ModulationTypeDropDownLabel = uilabel(comp.SimulationPanel);
            comp.ModulationTypeDropDownLabel.HorizontalAlignment = 'right';
            comp.ModulationTypeDropDownLabel.Position = [12 39 96 22];
            comp.ModulationTypeDropDownLabel.Text = 'Modulation Type:';

            % Create ModulationTypeDropDown
            comp.ModulationTypeDropDown = uidropdown(comp.SimulationPanel);
            comp.ModulationTypeDropDown.Items = {'1', '2', '3', '4'};
            comp.ModulationTypeDropDown.Position = [123 39 80 22];
            comp.ModulationTypeDropDown.Value = '1';

            % Create RunSimulationButton
            comp.RunSimulationButton = uibutton(comp.SimulationPanel, 'push');
            comp.RunSimulationButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunSimulationButtonPushed, true);
            comp.RunSimulationButton.Position = [877 25 122 28];
            comp.RunSimulationButton.Text = 'Run Simulation';

            % Create BERLabel
            comp.BERLabel = uilabel(comp.SimulationPanel);
            comp.BERLabel.FontSize = 18;
            comp.BERLabel.Position = [286 38 535 23];
            comp.BERLabel.Text = 'BER';

            % Create QLabel
            comp.QLabel = uilabel(comp.SimulationPanel);
            comp.QLabel.FontSize = 18;
            comp.QLabel.Position = [286 3 535 23];
            comp.QLabel.Text = 'Q';
        end
    end
end